classdef GraphicalUserInterface123 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        selectedOperation  matlab.ui.container.ButtonGroup
        radiobutton2       matlab.ui.control.RadioButton
        radiobutton1       matlab.ui.control.RadioButton
        text5              matlab.ui.control.Label
        text4              matlab.ui.control.Label
        text3              matlab.ui.control.Label
        text2              matlab.ui.control.Label
        pushbutton3        matlab.ui.control.Button
        pushbutton1        matlab.ui.control.Button
        axes2              matlab.ui.control.UIAxes
        axes1              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GraphicalUserInterface_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GraphicalUserInterface (see VARARGIN)
            
            % Choose default command line output for GraphicalUserInterface
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
             [filename, pathname] = uigetfile({'*.jpg';'*.bmp';'*.png'},'File Selector');
             if isequal(filename, 0)
                return
             end
             handles.myImage = strcat(pathname, filename);
             axes(handles.axes1);
             handles.myImage = imread(handles.myImage);
             imshow(handles.myImage);
             RB1Status = get(handles.radiobutton1, 'Value');
             RB2Status = get(handles.radiobutton2, 'Value');
             RB1Status;
             RB2Status;
             if RB1Status == 1
                h = drawfreehand(gca);
                xy = h.Position;
                xCoordinates = xy(:, 1);
                yCoordinates = xy(:, 2);
                handles.xCoordinates = xCoordinates;
                handles.yCoordinates = yCoordinates;
            
                % Changing value at those pixels which have value as 255 to have pixel value as 254.
                % This is being done to ensure that the region that is to be inpainted has
                % not pixel value overlap with any of the pixels in the original region
                handles.myImage(handles.myImage == 255) = 254;
                uploadedImage = handles.myImage;
                handles.uploadedImage = uploadedImage;
            
                numberOfCoordinates = (length(handles.myImage(:, 1, 1)))*(length(handles.myImage(1, :, 1)));
                imageCoordinate = zeros(numberOfCoordinates, 2);
                var = 1;
                for i = 1:length(handles.myImage(:, 1, 1))
                    for j = 1:length(handles.myImage(1, :, 1))
                        imageCoordinate(var, 2) = i;
                        imageCoordinate(var, 1) = j;
                        var = var + 1;
                    end
                end
                inPolygonOrNot = inpolygon(imageCoordinate(:, 1), imageCoordinate(:, 2), xCoordinates, yCoordinates);
                for someVar = 1: numberOfCoordinates
                    if inPolygonOrNot(someVar) == 1
                        handles.myImage(imageCoordinate(someVar, 2), imageCoordinate(someVar, 1), 1) = 0;
                        handles.myImage(imageCoordinate(someVar, 2), imageCoordinate(someVar, 1), 2) = 255;
                        handles.myImage(imageCoordinate(someVar, 2), imageCoordinate(someVar, 1), 3) = 0;
                    end
                end
            
                %Let's construct the mask that we are going to use later
                mask = zeros(length(handles.myImage(:, 1, 1)), length(handles.myImage(1, :, 1)));
                for someVar = 1:numberOfCoordinates
                    if inPolygonOrNot(someVar) == 1
                        mask(imageCoordinate(someVar, 2), imageCoordinate(someVar, 1)) = 255;
                    end
                end
                handles.mask = mask;
                imshow(handles.myImage)
             else
                 handles.uploadedImage = handles.myImage;
                 h1 = drawpolyline(gca, 'Closed', false);
                 foresub = getPosition(h1);
                 foregroundInd = sub2ind(size(handles.myImage), foresub(:, 2), foresub(:, 1));
                 imshow(handles.myImage);
                 h2 = drawpolyline(gca, 'Closed', false);
                 backsub = getPosition(h2);
                 backgroundInd = sub2ind(size(handles.myImage), backsub(:, 2), backsub(:, 1));
                 imshow(handles.myImage)
                 handles.foregroundIndex = round(foregroundInd);
                 handles.backgroundIndex = round(backgroundInd);
             end
             handles.RB1Status = RB1Status;
             handles.RB2Status = RB2Status;
             % save the updated handles object
             guidata(hObject,handles);
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isfield(handles,'uploadedImage')
                if handles.RB1Status == 1
                    originalImage = handles.uploadedImage;
                    imageFilled1 = regionfill(originalImage(:, :, 1), handles.xCoordinates, handles.yCoordinates);
                    imageFilled2 = regionfill(originalImage(:, :, 2), handles.xCoordinates, handles.yCoordinates);
                    imageFilled3 = regionfill(originalImage(:, :, 3), handles.xCoordinates, handles.yCoordinates);
                    imageFilled(:, :, 1) = imageFilled1;
                    imageFilled(:, :, 2) = imageFilled2;
                    imageFilled(:, :, 3) = imageFilled3;
                    mask = handles.mask;
                    mask = mat2gray(mask);
                    psz = 15;
                    [inpaintedImage, C, D, fillMovie] = inpainting(imageFilled, mask, psz);
                    inpaintedImage = uint8(inpaintedImage);
                    handles.modifiedImage1 = inpaintedImage;
                    axes(handles.axes2)
                    imshow(handles.modifiedImage1)
                    implay(fillMovie);
                elseif handles.RB2Status == 1
                    originalImage = handles.uploadedImage;
                    foregroundInd = handles.foregroundIndex;
                    backgroundInd = handles.backgroundIndex;
                    L = superpixels(originalImage, 500);
                    BW = lazysnapping(originalImage, L, foregroundInd, backgroundInd);
                    maskedImage = originalImage;
                    maskedImage(repmat(~BW, [1 1 3])) = 0;
                    H = fspecial('motion', 40, 65);
                    MotionBlur = imfilter(originalImage, H, 'replicate');
                    for i = 1:length(MotionBlur(:, 1, 1))
                        for j = 1:length(MotionBlur(1, :, 1))
                            if maskedImage(i, j, 1) == 0
                                maskedImage(i, j, 1) = MotionBlur(i, j, 1);
                                maskedImage(i, j, 2) = MotionBlur(i, j, 2);
                                maskedImage(i, j, 3) = MotionBlur(i, j, 3);
                            end
                        end
                    end
                    handles.modifiedImage2 = maskedImage;
                    axes(handles.axes2);
                    imshow(handles.modifiedImage2);
                end
                % save the updated handles object
                guidata(hObject,handles);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GraphicalUserInterface';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XTickLabel = '';
            app.axes1.YTickLabel = '';
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.XTickLabel = '';
            app.axes2.YTickLabel = '';
            app.axes2.Tag = 'axes2';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Upload New Image';
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'Process!';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Object Removing Software';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = {'Arjun Verma,(B.Tech-152110)'; 'Cluster Innovation Centre'; 'University of Delhi.'};
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Original Image';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Processed Image';
            app.selectedOperation = uibuttongroup(app.figure1);
            app.selectedOperation.Title = 'Select Operation';
            app.selectedOperation.Tag = 'selectedOperation';
            app.radiobutton1 = uiradiobutton(app.selectedOperation);
            app.radiobutton1.Tag = 'radiobutton1';
            app.radiobutton1.Text = 'Remove Object';
            app.radiobutton1.Value = true;
            app.radiobutton2 = uiradiobutton(app.selectedOperation);
            app.radiobutton2.Tag = 'radiobutton2';
            app.radiobutton2.Text = 'Motion Blur';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraphicalUserInterface123(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GraphicalUserInterface_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end